classdef SwipeBotApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SwipeBotAppUIFigure       matlab.ui.Figure
        LoadSwipeBotSystemButton  matlab.ui.control.Button
        CleanWindowButton         matlab.ui.control.Button
        Title                     matlab.ui.control.Label
        Subtitle                  matlab.ui.control.Label
        SafeCoLogo                matlab.ui.control.Image
        EmergencyStopButton       matlab.ui.control.StateButton
        TestButton                matlab.ui.control.Button
        TextArea                  matlab.ui.control.TextArea
        MovetoHomeButton          matlab.ui.control.Button
        MovetoStoreButton         matlab.ui.control.Button
        StatusMessagesLabel       matlab.ui.control.Label
    end

    
    properties (Access = public)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadSwipeBotSystemButton
        function LoadSwipeBotSystemButtonPushed(app, event)
            clf                        % clear all windows
            clear all
            clc
            swipebot = SwipeBot;   % generate the SwipeBot
            assignin('base', "swipebot", swipebot);
        end

        % Button pushed function: CleanWindowButton
        function CleanWindowButtonPushed(app, event)
            swipebot = evalin("base","swipebot");
            swipebot.CleanWindow(app);  % start Window-Cleaning-Program
        end

        % Button pushed function: TestButton
        function TestButtonPushed(app, event)
            swipebot = evalin("base","swipebot");
            swipebot.Test(app);
            %app.InfoText.Value = "test";
        end

        % Button pushed function: MovetoHomeButton
        function MovetoHomeButtonPushed(app, event)
            swipebot = evalin("base","swipebot");
            swipebot.MoveTo("home",app);  % move robots to home position
        end

        % Button pushed function: MovetoStoreButton
        function MovetoStoreButtonPushed(app, event)
            swipebot = evalin("base","swipebot");
            swipebot.MoveTo("store",app);  % move robots to store position
        end

        % Value changed function: EmergencyStopButton
        function EmergencyStopButtonValueChanged(app, event)
            eStop = app.EmergencyStopButton.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SwipeBotAppUIFigure = uifigure('Visible', 'off');
            app.SwipeBotAppUIFigure.Name = 'SwipeBotApp';
            app.LoadSwipeBotSystemButton = uibutton(app.SwipeBotAppUIFigure, 'push');
            app.LoadSwipeBotSystemButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSwipeBotSystemButtonPushed, true);
            app.LoadSwipeBotSystemButton.Text = 'Load SwipeBot-System';
            app.CleanWindowButton = uibutton(app.SwipeBotAppUIFigure, 'push');
            app.CleanWindowButton.ButtonPushedFcn = createCallbackFcn(app, @CleanWindowButtonPushed, true);
            app.CleanWindowButton.Text = 'Clean Window';
            app.Title = uilabel(app.SwipeBotAppUIFigure);
            app.Title.Text = 'SwipeBot';
            app.Subtitle = uilabel(app.SwipeBotAppUIFigure);
            app.Subtitle.Text = 'See the future clearer';
            app.SafeCoLogo = uiimage(app.SwipeBotAppUIFigure);
            app.SafeCoLogo.ImageSource = 'SafeCo Logo.png';
            app.EmergencyStopButton = uibutton(app.SwipeBotAppUIFigure, 'state');
            app.EmergencyStopButton.ValueChangedFcn = createCallbackFcn(app, @EmergencyStopButtonValueChanged, true);
            app.EmergencyStopButton.Text = 'Emergency Stop';
            app.TestButton = uibutton(app.SwipeBotAppUIFigure, 'push');
            app.TestButton.ButtonPushedFcn = createCallbackFcn(app, @TestButtonPushed, true);
            app.TestButton.Text = 'Test';
            app.TextArea = uitextarea(app.SwipeBotAppUIFigure);
            app.MovetoHomeButton = uibutton(app.SwipeBotAppUIFigure, 'push');
            app.MovetoHomeButton.ButtonPushedFcn = createCallbackFcn(app, @MovetoHomeButtonPushed, true);
            app.MovetoHomeButton.Text = {'Move to '; 'Home'};
            app.MovetoStoreButton = uibutton(app.SwipeBotAppUIFigure, 'push');
            app.MovetoStoreButton.ButtonPushedFcn = createCallbackFcn(app, @MovetoStoreButtonPushed, true);
            app.MovetoStoreButton.Text = {'Move to'; 'Store'};
            app.StatusMessagesLabel = uilabel(app.SwipeBotAppUIFigure);
            app.StatusMessagesLabel.Text = 'Status Messages:';
            % Show the figure after all components are created
            app.SwipeBotAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SwipeBotApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SwipeBotAppUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SwipeBotAppUIFigure)
        end
    end
end